classdef traffic_light < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        ControlLabel  matlab.ui.control.Label
        AutoPanel     matlab.ui.container.Panel
        Label         matlab.ui.control.Label
        ManualPanel   matlab.ui.container.Panel
        GreenButton   matlab.ui.control.Button
        YellowButton  matlab.ui.control.Button
        RedButton     matlab.ui.control.Button
        EditField     matlab.ui.control.NumericEditField
        AutoButton    matlab.ui.control.Button
        ManualButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        ardobj = arduino(); % Description
        Auto = false; % Description
        delay = 0;
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RedButton
        function RedButtonPushed(app, event)
            writeDigitalPin(app.ardobj, 'D5', 0);
            writeDigitalPin(app.ardobj, 'D6', 0);
            writeDigitalPin(app.ardobj, 'D7', 1);
        end

        % Button pushed function: YellowButton
        function YellowButtonPushed(app, event)
            writeDigitalPin(app.ardobj, 'D5', 0);
            writeDigitalPin(app.ardobj, 'D6', 1);
            writeDigitalPin(app.ardobj, 'D7', 0);
        end

        % Button pushed function: GreenButton
        function GreenButtonPushed(app, event)
            writeDigitalPin(app.ardobj, 'D5', 1);
            writeDigitalPin(app.ardobj, 'D6', 0);
            writeDigitalPin(app.ardobj, 'D7', 0);
        end

        % Button pushed function: AutoButton
        function AutoButtonPushed(app, event)
            app.Auto = true;
            app.AutoPanel.Enable="on";
            app.ManualPanel.Enable = "off"
            
            
            while(app.Auto == true)
                
                app.delay = app.EditField.Value;
                while(app.Auto == true && app.delay > 0)
                    app.Label.Text = num2str(app.delay);
                    app.delay = app.delay - 1;
                    writeDigitalPin(app.ardobj, 'D5', 0);
                    writeDigitalPin(app.ardobj, 'D6', 0);
                    writeDigitalPin(app.ardobj, 'D7', 1);
                    pause(1);
                end
                      
                app.delay = app.EditField.Value;
                while(app.Auto == true && app.delay > 0) 
                    app.Label.Text = num2str(app.delay);
                    app.delay = app.delay - 1;
                    writeDigitalPin(app.ardobj, 'D5', 0);
                    writeDigitalPin(app.ardobj, 'D6', 1);
                    writeDigitalPin(app.ardobj, 'D7', 0);
                    pause(1);
                end   
                       
                app.delay = app.EditField.Value;
                while(app.Auto == true && app.delay > 0)  
                    app.Label.Text = num2str(app.delay);
                    app.delay = app.delay - 1;
                    writeDigitalPin(app.ardobj, 'D5', 1);
                    writeDigitalPin(app.ardobj, 'D6', 0);
                    writeDigitalPin(app.ardobj, 'D7', 0);
                    pause(1);
                end
                
            end
        end

        % Button pushed function: ManualButton
        function ManualButtonPushed(app, event)
            app.Auto = false;
            app.AutoPanel.Enable="off";
            app.ManualPanel.Enable = "on";
            app.Label.Text = "0";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ManualButton = uibutton(app.UIFigure, 'push');
            app.ManualButton.ButtonPushedFcn = createCallbackFcn(app, @ManualButtonPushed, true);
            app.ManualButton.Text = 'Manual';
            app.AutoButton = uibutton(app.UIFigure, 'push');
            app.AutoButton.ButtonPushedFcn = createCallbackFcn(app, @AutoButtonPushed, true);
            app.AutoButton.Text = 'Auto';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.ManualPanel = uipanel(app.UIFigure);
            app.ManualPanel.Title = 'Manual';
            app.RedButton = uibutton(app.ManualPanel, 'push');
            app.RedButton.ButtonPushedFcn = createCallbackFcn(app, @RedButtonPushed, true);
            app.RedButton.Text = 'Red';
            app.YellowButton = uibutton(app.ManualPanel, 'push');
            app.YellowButton.ButtonPushedFcn = createCallbackFcn(app, @YellowButtonPushed, true);
            app.YellowButton.Text = 'Yellow';
            app.GreenButton = uibutton(app.ManualPanel, 'push');
            app.GreenButton.ButtonPushedFcn = createCallbackFcn(app, @GreenButtonPushed, true);
            app.GreenButton.Text = 'Green';
            app.AutoPanel = uipanel(app.UIFigure);
            app.AutoPanel.Title = 'Auto';
            app.Label = uilabel(app.AutoPanel);
            app.Label.Text = '0';
            app.ControlLabel = uilabel(app.UIFigure);
            app.ControlLabel.Text = 'Control';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = traffic_light

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end